classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RecognizeButton             matlab.ui.control.Button
        PreviewButton               matlab.ui.control.Button
        ClickifyouwanttoseepreviewfromKinectLabel  matlab.ui.control.Label
        ClicktorecognizefruitLabel  matlab.ui.control.Label
        ResultTextAreaLabel         matlab.ui.control.Label
        ResultTextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        colorDevice;
        depthDevice;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.colorDevice = imaq.VideoDevice('kinect',1);
            app.depthDevice = imaq.VideoDevice('kinect',2);
            app.colorDevice();
            app.depthDevice();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            clearvars;
            close all;
            clc;
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            %otwiera w nowym oknie, ale bez opóźnień
            preview(app.colorDevice);
        end

        % Button pushed function: RecognizeButton
        function RecognizeButtonPushed(app, event)
            app.ResultTextArea.Value = "Recognizing...";
            colorImage = app.colorDevice();
            depthImage = app.depthDevice();
            net = importKerasNetwork('model.h5');
            vals = procesData(colorImage,depthImage,app.depthDevice);
            vals = vals(:,:,[4 5 6 1 2 3]);
            vals(:,:,4:5) =1+vals(:,:,4:5);
            vals(:,:,1:3) = vals(:,:,1:3)/255;
            vals(:,:,4) = vals(:,:,4)/1.5461595;
            vals(:,:,5) = vals(:,:,5)/1.1899515;
            vals(:,:,6) = vals(:,:,6)/0.82600003;
            label = classify(net,vals);
            classes = ["Apple" "Banana" "Grape" "Kiwi" "Orange"];
            app.ResultTextArea.Value = classes(label);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RecognizeButton = uibutton(app.UIFigure, 'push');
            app.RecognizeButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizeButtonPushed, true);
            app.RecognizeButton.Text = 'Recognize ';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.ClickifyouwanttoseepreviewfromKinectLabel = uilabel(app.UIFigure);
            app.ClickifyouwanttoseepreviewfromKinectLabel.Text = 'Click if you want to see preview from Kinect.';
            app.ClicktorecognizefruitLabel = uilabel(app.UIFigure);
            app.ClicktorecognizefruitLabel.Text = 'Click to recognize fruit.';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ResultTextArea.Editable = 'off';
            app.ResultTextArea.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end